classdef Audiogram < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AudiogramPanel                matlab.ui.container.Panel
        ZakoczbadaniePanel            matlab.ui.container.Panel
        WartoscUbytkuPrawe            matlab.ui.control.Label
        WartoscUbytkuLewe             matlab.ui.control.Label
        UbytekpraweLabel              matlab.ui.control.Label
        UbytekleweLabel               matlab.ui.control.Label
        KoniecbadaniaButton           matlab.ui.control.Button
        Label_2                       matlab.ui.control.Label
        AutorzyPanel                  matlab.ui.container.Panel
        WEL18EC1S1Label               matlab.ui.control.Label
        ukaszKopczyskiLabel           matlab.ui.control.Label
        JakubWanatLabel               matlab.ui.control.Label
        BadaniePanel                  matlab.ui.container.Panel
        show_dB                       matlab.ui.control.Label
        show_f                        matlab.ui.control.Label
        GonodBLabel                   matlab.ui.control.Label
        CzstotliwoHzLabel             matlab.ui.control.Label
        OdtwarzajButton               matlab.ui.control.Button
        WybrbadanegouchaButtonGroup   matlab.ui.container.ButtonGroup
        UcholeweButton                matlab.ui.control.RadioButton
        UchopraweButton               matlab.ui.control.RadioButton
        KalibracjaPanel               matlab.ui.container.Panel
        InfoButton_Kalibracja         matlab.ui.control.Button
        NIEButton                     matlab.ui.control.Button
        TAKButton                     matlab.ui.control.Button
        CzysychadwikpoklikniciukalibracjiLabel  matlab.ui.control.Label
        KalibracjaButton              matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        UrzdzeniedopomiarusuchuLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        
        CZESTOTLIWOSC = [125 500 1000 2000 3000 4000 6000 8000 10000];
        AMPLITUDA_LEWE = [0 0 0 0 0 0 0 0 0];
        AMPLITUDA_PRAWE = [0 0 0 0 0 0 0 0 0];
        TEMP=1;
        TEMP2=1;
        Zwiekszenie_sygnalu_lewe=0;
        Zwiekszenie_sygnalu_prawe=0;
        UBYTEK_LEWA=0;
        UBYTEK_PRAWA=0;
        
    end
       
    
 %   methods (Access = public)
        
 %       function results = func(app)
            
  %      end
 %   end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: KalibracjaButton
        function KalibracjaButtonPushed(app, event)
           %kalibracja
        fs=48000;
        f=1000;
        t=0:1/fs:1;
        A=1;
        y=A*sin(2*pi*f*t);
      %  Py=10*log10(sum(y.^2));
        coef=10.^(5/20);
        yy=y.*coef;
   %     Pyy=10*log10(sum(yy.^2));     
        
        zz=[];
    for i=1:10      
        z1=y(1,1:48000);
        z2=yy(1,1:48000);
        zz=[zz z1 z2];
    end
        z4=zz/max(abs(zz));
       
           sound(z4,fs);
           pause(2/20)
          
        end

        % Button pushed function: TAKButton
        function TAKButtonPushed(app, event)
            clear sound;
            msgbox('Przejdz do punktu drugiego');
            pause(2/20)
        end

        % Button pushed function: NIEButton
        function NIEButtonPushed(app, event)
            clear sound;
            msgbox('Sprawdz czy podłaczyłeś sprzęt');
            pause(2/20)
        end

        % Callback function
        function RozpocznijbadanieButtonValueChanged(app, event)

        end

        % Button pushed function: OdtwarzajButton
        function OdtwarzajButtonPushed(app, event)
            if (app.UcholeweButton.Value) 
            clear sound;   
            fs=48000;     
            t=0:1/fs:1; 
            A=0.001;
            y=A*sin(2*pi*app.CZESTOTLIWOSC(app.TEMP)*t); 
            coef=1*10.^(app.Zwiekszenie_sygnalu_lewe/20); 
            Wyjscie=(y.*coef); 
            koncowe = [Wyjscie;zeros(size(t))];
            sound(koncowe,fs)
            
            answer = questdlg('Czy slyszysz dzwiek', 'Pomiar', 'Słyszę', 'Nie słyszę','end');
            
            switch answer
                case 'Słyszę'
                    
                    clear sound;
                    app.AMPLITUDA_LEWE(app.TEMP)=app.Zwiekszenie_sygnalu_lewe; 
                    app.Zwiekszenie_sygnalu_lewe=0;
                    
                    if (app.TEMP<9)
                    app.TEMP=app.TEMP+1;
                    else
                    msgbox('Zmien na drugie ucho albo zakoncz badanie');
                    end
                 
                case 'Nie słyszę'
                    
                   app.Zwiekszenie_sygnalu_lewe=app.Zwiekszenie_sygnalu_lewe+10;
                    %app.Zwiekszenie_sygnalu_lewe=
                 
                    if(app.Zwiekszenie_sygnalu_lewe==150)
                        app.AMPLITUDA_LEWE(app.TEMP)=140;
                        app.Zwiekszenie_sygnalu_lewe=0;
                        if (app.TEMP<9)
                        app.TEMP=app.TEMP+1;
                        end
                    end
            end
            if (app.TEMP<10)
            zapasowa=string(app.CZESTOTLIWOSC(app.TEMP));
            app.show_f.Text=zapasowa;
            end
            zapasowa2=string(app.Zwiekszenie_sygnalu_lewe);
            app.show_dB.Text=zapasowa2;
            
            else 
            clear sound;   
            fs=48000;     
            t=0:1/fs:1; 
            A=1;
            y=A*sin(2*pi*app.CZESTOTLIWOSC(app.TEMP2)*t); 
            coef=0.01*10.^(app.Zwiekszenie_sygnalu_prawe/20); 
            yy=y.*coef; 
            koncowe = [zeros(size(t));yy];
            sound(koncowe,fs)
            
            answer = questdlg('Czy slyszysz dzwiek', 'Pomiar', 'Słyszę', 'Nie słyszę','end');
            
            switch answer
                case 'Słyszę'
                    clear sound;
                    
                    app.AMPLITUDA_PRAWE(app.TEMP2)=app.Zwiekszenie_sygnalu_prawe;  
                    app.Zwiekszenie_sygnalu_prawe=0;
                    
                    if (app.TEMP2<9)
                    app.TEMP2=app.TEMP2+1;
                    else
                    msgbox('Zmien na drugie ucho albo zakoncz badanie'); 
                    end
                    
                case 'Nie słyszę'
                    app.Zwiekszenie_sygnalu_prawe=app.Zwiekszenie_sygnalu_prawe+10;
                    
                    if(app.Zwiekszenie_sygnalu_prawe==150)
                        app.AMPLITUDA_PRAWE(app.TEMP2)=140;
                        app.Zwiekszenie_sygnalu_prawe=0;
                        if(app.TEMP2<9)
                        app.TEMP2=app.TEMP2+1;
                        
                        end
                    end
            end
          
            if (app.TEMP2<10)
            zapasowa_prawa=string(app.CZESTOTLIWOSC(app.TEMP2));
            app.show_f.Text=zapasowa_prawa;
            end
            zapasowa2_prawa=string(app.Zwiekszenie_sygnalu_prawe);
            app.show_dB.Text=zapasowa2_prawa;
            
            end


            plot(app.UIAxes,app.CZESTOTLIWOSC,app.AMPLITUDA_LEWE,"g-*",app.CZESTOTLIWOSC,app.AMPLITUDA_PRAWE,"r-o")
            
            
        end

        % Button pushed function: KoniecbadaniaButton
        function KoniecbadaniaButtonPushed(app, event)

            %CZESTOTLIWOSC = [125(1) 500(2) 1000(3) 2000(4) 3000(5) 4000(6) 6000(7) 8000(8) 10000(9)];
            
           if((app.AMPLITUDA_LEWE(4) - app.AMPLITUDA_LEWE(2)) > 40)
                
                app.UBYTEK_LEWA = (app.AMPLITUDA_LEWE(2) + app.AMPLITUDA_LEWE(3) + app.AMPLITUDA_LEWE(4) + app.AMPLITUDA_LEWE(6))/4;    
                
            elseif((app.AMPLITUDA_LEWE(4) - app.AMPLITUDA_LEWE(2)) > 40 && app.AMPLITUDA_LEWE(6) > app.AMPLITUDA_LEWE(4))
                
                 app.UBYTEK_LEWA = (app.AMPLITUDA_LEWE(2) + app.AMPLITUDA_LEWE(3) + app.AMPLITUDA_LEWE(6))/3; 
                
            else
                 app.UBYTEK_LEWA = (app.AMPLITUDA_LEWE(2) + app.AMPLITUDA_LEWE(3) + app.AMPLITUDA_LEWE(4))/3;
            end
            
            if((app.AMPLITUDA_PRAWE(4) - app.AMPLITUDA_PRAWE(2)) > 40)
                
                app.UBYTEK_PRAWA = (app.AMPLITUDA_PRAWE(2) + app.AMPLITUDA_PRAWE(3) + app.AMPLITUDA_PRAWE(4) + app.AMPLITUDA_PRAWE(6))/4;    
                
           elseif((app.AMPLITUDA_PRAWE(4) - app.AMPLITUDA_PRAWE(2)) > 40 && app.AMPLITUDA_PRAWE(6) > app.AMPLITUDA_PRAWE(4))
                
                app.UBYTEK_PRAWA = (app.AMPLITUDA_PRAWE(2) + app.AMPLITUDA_PRAWE(3) + app.AMPLITUDA_PRAWE(6))/3; 
                
            else
                app.UBYTEK_PRAWA = (app.AMPLITUDA_PRAWE(2) + app.AMPLITUDA_PRAWE(3) + app.AMPLITUDA_PRAWE(4))/3;
                
            end
               
            if(app.UBYTEK_PRAWA>app.UBYTEK_LEWA)
                
                if(app.UBYTEK_LEWA-app.UBYTEK_PRAWA>=25)
                    UBYTEK_KONCOWY=app.UBYTEK_LEWA+5;
                else
                    UBYTEK_KONCOWY=app.UBYTEK_LEWA;
                end

            else
                if(app.UBYTEK_PRAWA-app.UBYTEK_LEWA>=25)
                    UBYTEK_KONCOWY=app.UBYTEK_PRAWA+5;
                else
                    UBYTEK_KONCOWY=app.UBYTEK_PRAWA;
                end
 
            end


            if(UBYTEK_KONCOWY<20)
                msgbox('Wynik badania : Słuch w normie');
            elseif((UBYTEK_KONCOWY>=20)&&(UBYTEK_KONCOWY<=40))
                msgbox('Wynik badania : lekkie uszkodzenie słuchu.');
            elseif((UBYTEK_KONCOWY>40)&&(UBYTEK_KONCOWY<=70))
                 msgbox('Wynik badania : Umiarkowane uszkodzenie słuchu - uważaj na siebie.');
            elseif((UBYTEK_KONCOWY>70)&&(UBYTEK_KONCOWY<=90))
                msgbox('Wynik badania : Znaczne uszkodzenie słuchu - Zgłoś się do specjalisty.');
            elseif((UBYTEK_KONCOWY>90)&&(UBYTEK_KONCOWY<=120))
                 msgbox('Wynik badania : Głębokie uszkodzenie słuchu - Zgłoś się do specjalisty.');
            else
                 msgbox('Wynik badania : Całkowita głuchota');
            end
          
            tymczasowa_lewa=string(app.UBYTEK_LEWA);
            app.WartoscUbytkuLewe.Text=tymczasowa_lewa;
            
            tymczasowa_prawa=string(app.UBYTEK_PRAWA);
            app.WartoscUbytkuPrawe.Text=tymczasowa_prawa;
            
            
            % zapasowa2_prawa=string(app.Zwiekszenie_sygnalu_prawe);
            % app.show_dB.Text=zapasowa2_prawa;


            %  switch (UBYTEK_KONCOWY)
                
            %        case (UBYTEK_KONCOWY<20)
            %             msgbox('Wynik badania : Słuch w normie');
            %        case (UBYTEK_KONCOWY>=20)&&(UBYTEK_KONCOWY<=40)
            %            msgbox('Wynik badania : lekkie uszkodzenie słuchu.');
            %       case (UBYTEK_KONCOWY>40)&&(UBYTEK_KONCOWY<=70)
            %            msgbox('Wynik badania : Umiarkowane uszkodzenie słuchu - uważaj na siebie.');
            %        case (UBYTEK_KONCOWY>70)&&(UBYTEK_KONCOWY<=90)
            %           msgbox('Wynik badania : Znaczne uszkodzenie słuchu - Zgłoś się do specjalisty.');
           %       case (UBYTEK_KONCOWY>90)&&(UBYTEK_KONCOWY<=120)
            %            msgbox('Wynik badania : Głębokie uszkodzenie słuchu - Zgłoś się do specjalisty.');
            %       case (UBYTEK_KONCOWY>120)
            %            msgbox('Wynik badania : Całkowita głuchota');
           % end
   
        end

        % Button pushed function: InfoButton_Kalibracja
        function InfoButton_KalibracjaPushed(app, event)
            msgbox('Ustaw poziom glosnosci w taki sposob, żeby słyszeć z dwóch sygnałów tylko jeden sygnał - głośniejszy', 'Info','help');
        end

        % Callback function
        function JakprzeprowadzibadanieButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UrzdzeniedopomiarusuchuLabel = uilabel(app.UIFigure);
            app.UrzdzeniedopomiarusuchuLabel.Text = 'Urządzenie do pomiaru słuchu';
            app.AudiogramPanel = uipanel(app.UIFigure);
            app.AudiogramPanel.Title = 'Audiogram';
            app.UIAxes = uiaxes(app.AudiogramPanel);
            title(app.UIAxes, 'Wykres badania audiogramem')
            xlabel(app.UIAxes, 'Częstotliwość [Hz]')
            ylabel(app.UIAxes, 'Ubytek słuchu [dB]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XTick = [125 500 1000 2000 3000 4000 6000 8000 10000];
            app.UIAxes.XTickLabel = {'125'; '500'; '1000'; '2000'; '3000'; '4000'; '6000'; '8000'; '10000'};
            app.UIAxes.YTick = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140];
            app.UIAxes.YTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'; '110'; '120'; '130'; '140'};
            app.KalibracjaPanel = uipanel(app.AudiogramPanel);
            app.KalibracjaPanel.Title = '1. Kalibracja';
            app.Label = uilabel(app.KalibracjaPanel);
            app.Label.Text = {'Przed rozpoczeciem badania nalezy skalibrować aplikacje.                   '; 'W tym celu proszę o naciśnięcie przycisku kalibracja'};
            app.KalibracjaButton = uibutton(app.KalibracjaPanel, 'push');
            app.KalibracjaButton.ButtonPushedFcn = createCallbackFcn(app, @KalibracjaButtonPushed, true);
            app.KalibracjaButton.Text = 'Kalibracja';
            app.CzysychadwikpoklikniciukalibracjiLabel = uilabel(app.KalibracjaPanel);
            app.CzysychadwikpoklikniciukalibracjiLabel.Text = 'Czy słychać dźwięk po kliknięciu kalibracji ? ';
            app.TAKButton = uibutton(app.KalibracjaPanel, 'push');
            app.TAKButton.ButtonPushedFcn = createCallbackFcn(app, @TAKButtonPushed, true);
            app.TAKButton.Text = 'TAK';
            app.NIEButton = uibutton(app.KalibracjaPanel, 'push');
            app.NIEButton.ButtonPushedFcn = createCallbackFcn(app, @NIEButtonPushed, true);
            app.NIEButton.Text = {'NIE'; ''};
            app.InfoButton_Kalibracja = uibutton(app.KalibracjaPanel, 'push');
            app.InfoButton_Kalibracja.ButtonPushedFcn = createCallbackFcn(app, @InfoButton_KalibracjaPushed, true);
            app.InfoButton_Kalibracja.Text = 'Info';
            app.BadaniePanel = uipanel(app.AudiogramPanel);
            app.BadaniePanel.Title = '2. Badanie';
            app.WybrbadanegouchaButtonGroup = uibuttongroup(app.BadaniePanel);
            app.WybrbadanegouchaButtonGroup.Title = 'Wybór badanego ucha';
            app.UchopraweButton = uiradiobutton(app.WybrbadanegouchaButtonGroup);
            app.UchopraweButton.Text = 'Ucho prawe';
            app.UchopraweButton.Value = true;
            app.UcholeweButton = uiradiobutton(app.WybrbadanegouchaButtonGroup);
            app.UcholeweButton.Text = 'Ucho lewe';
            app.OdtwarzajButton = uibutton(app.BadaniePanel, 'push');
            app.OdtwarzajButton.ButtonPushedFcn = createCallbackFcn(app, @OdtwarzajButtonPushed, true);
            app.OdtwarzajButton.Text = 'Odtwarzaj';
            app.CzstotliwoHzLabel = uilabel(app.BadaniePanel);
            app.CzstotliwoHzLabel.Text = 'Częstotliwość[Hz] : ';
            app.GonodBLabel = uilabel(app.BadaniePanel);
            app.GonodBLabel.Text = 'Głośność [dB] : ';
            app.show_f = uilabel(app.BadaniePanel);
            app.show_f.Text = '';
            app.show_dB = uilabel(app.BadaniePanel);
            app.show_dB.Text = '';
            app.AutorzyPanel = uipanel(app.AudiogramPanel);
            app.AutorzyPanel.Title = 'Autorzy';
            app.JakubWanatLabel = uilabel(app.AutorzyPanel);
            app.JakubWanatLabel.Text = 'Jakub Wanat';
            app.ukaszKopczyskiLabel = uilabel(app.AutorzyPanel);
            app.ukaszKopczyskiLabel.Text = 'Łukasz Kopczyński';
            app.WEL18EC1S1Label = uilabel(app.AutorzyPanel);
            app.WEL18EC1S1Label.Text = 'WEL18EC1S1';
            app.ZakoczbadaniePanel = uipanel(app.AudiogramPanel);
            app.ZakoczbadaniePanel.Title = '3 Zakończ badanie';
            app.Label_2 = uilabel(app.ZakoczbadaniePanel);
            app.Label_2.Text = {'Po zakończeniu badania '; 'naciśnij przycisk zakończ badanie '; 'Wyniki zostaną automatycznie wyświetlone. '};
            app.KoniecbadaniaButton = uibutton(app.ZakoczbadaniePanel, 'push');
            app.KoniecbadaniaButton.ButtonPushedFcn = createCallbackFcn(app, @KoniecbadaniaButtonPushed, true);
            app.KoniecbadaniaButton.Text = 'Koniec badania';
            app.UbytekleweLabel = uilabel(app.ZakoczbadaniePanel);
            app.UbytekleweLabel.Text = 'Ubytek lewe';
            app.UbytekpraweLabel = uilabel(app.ZakoczbadaniePanel);
            app.UbytekpraweLabel.Text = 'Ubytek prawe';
            app.WartoscUbytkuLewe = uilabel(app.ZakoczbadaniePanel);
            app.WartoscUbytkuLewe.Text = '';
            app.WartoscUbytkuPrawe = uilabel(app.ZakoczbadaniePanel);
            app.WartoscUbytkuPrawe.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Audiogram

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end